classdef appMaquinaPVISED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AM2SEDUIFigure                  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        ddProblemas                     matlab.ui.control.DropDown
        ProblemasdinmicosDropDownLabel  matlab.ui.control.Label
        bAtualizar                      matlab.ui.control.Button
        bgMetodos                       matlab.ui.container.ButtonGroup
        rbTodos                         matlab.ui.control.RadioButton
        rbRK4                           matlab.ui.control.RadioButton
        rbRK2                           matlab.ui.control.RadioButton
        rbEulerM                        matlab.ui.control.RadioButton
        rbEuler                         matlab.ui.control.RadioButton
        DadosPVI                        matlab.ui.container.Panel
        v0Label                         matlab.ui.control.Label
        eV0                             matlab.ui.control.NumericEditField
        eG                              matlab.ui.control.EditField
        gtuvLabel                       matlab.ui.control.Label
        eU0                             matlab.ui.control.NumericEditField
        u0Label                         matlab.ui.control.Label
        nLabel                          matlab.ui.control.Label
        eN                              matlab.ui.control.NumericEditField
        bLabel                          matlab.ui.control.Label
        eB                              matlab.ui.control.NumericEditField
        eA                              matlab.ui.control.NumericEditField
        aLabel                          matlab.ui.control.Label
        eF                              matlab.ui.control.EditField
        ftuvLabel                       matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        EL                              matlab.ui.control.Lamp
        ErroLampLabel                   matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        FicheiroMenu                    matlab.ui.container.Menu
        mRelatorio                      matlab.ui.container.Menu
        mSair                           matlab.ui.container.Menu
        SobreMenu                       matlab.ui.container.Menu
        mAutor                          matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
            app.eF.Editable = 'off';
            app.eG.Editable = 'off';
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            % 29/04/2021  Arménio Correia  armenioc@isec.pt
            
            %Adaptado por:
            %Nuno Domingues  a2020109910@isec.pt
            %Miguel Neves    a2020146521@isec.pt
            %Daniel Albino   a2020134077@isec.pt
            %
            %Data: 10/05/2021
            
            syms u(t) v(t)
            strF = app.eF.Value;
            f =@(t,u,v) eval(vectorize(strF));
            strG = app.eG.Value;
            g =@(t,u,v) eval(vectorize(strG));
           

            % Validação dos campos de entrada
            a = app.eA.Value;
            if (isempty(a) || ~isscalar(a) || ~isreal(a)) 
                errordlg("O parâmetro a deve ser ESCALAR e REAL","ERRO","modal")
            end
        
            b = app.eB.Value;            
            if (isempty(b) || ~isscalar(b) || ~isreal(b) || b<a) 
                errordlg("O parâmetro b deve ser ESCALAR, REAL e MAIOR que a","ERRO","modal")
            end        
       
            n = app.eN.Value;
            if (isempty(n) || ~isscalar(n) || ~isreal(n)) 
                errordlg("O parâmetro n deve ser ESCALAR e REAL","ERRO","modal")
            end        
      
            u0 = app.eU0.Value;
            if (isempty(u0) || ~isscalar(u0) || ~isreal(u0)) 
                errordlg("O parâmetro u0 deve ser ESCALAR e REAL","ERRO","modal")
            end
       
            v0 = app.eV0.Value;
            if (isempty(v0) || ~isscalar(v0) || ~isreal(v0)) 
               errordlg("O parâmetro v0 deve ser ESCALAR e REAL","ERRO","modal")
            end


            escolhabgMetodos = app.bgMetodos.SelectedObject;
            metodoSelecionado = find([app.rbEuler,...
                app.rbEulerM,...
                app.rbRK2,...
                app.rbRK4,...
                app.rbTodos]==escolhabgMetodos);

            erroFuncao = MException("MATLAB:bAtualizar:badF",...
                "Introduza uma função em t, u e v");

            try
                try
                    z = g(t,u,v);
                    app.eG.BackgroundColor = "w";
                catch
                    app.eG.BackgroundColor = [1,0,0]; %[r,g,b]
                    app.EL.Color = "r";
                    throw(erroFuncao)
                end

                if strG ~= "-sin(u)-0.3*v"
                    eqns = [diff(u,t) == f(t,u,v), diff(v,t) == g(t,u,v)]; 
                    ci = [u(0)==u0, v(0)==v0];
                    [sExataU,~] = dsolve(eqns,ci);
    
                    h =@(t) eval(vectorize(char(sExataU)));
                
                end
              

                [t,uEuler,~] = NEulerSED(f,g,a,b,n,u0,v0);
                [~,uEulerM,~] = NEulerMSED(f,g,a,b,n,u0,v0);
                [~,uRK2,~] = NRK2SED(f,g,a,b,n,u0,v0);
                [~,uRK4,~] = NRK4SED(f,g,a,b,n,u0,v0);
                  
                if strG ~= "-sin(u)-0.3*v"
                    uExata = h(t);
    
                    erroEuler = abs(uExata-uEuler);
                    erroEulerM = abs(uExata-uEulerM);
                    erroRK2 = abs(uExata-uRK2);
                    erroRK4 = abs(uExata-uRK4);
    
                    plot(app.UIAxes,t,uExata,"-ro")
                    hold(app.UIAxes,"on")
                end

                switch metodoSelecionado
                    case 1
                            plot(app.UIAxes,t,uEuler,"-g*")
                        if strG ~= "-sin(u)-0.3*v"
                            legenda = {'Exata','Euler'};
                            tabela = [t.',uExata.',uEuler.',erroEuler.'];
                            nomeColunas = {'t','Exata','Euler','erroEuler'};
                        else
                            legenda = {'Euler'};
                            tabela = [t.',uEuler.'];
                            nomeColunas = {'t','Euler'};
                        end
                    case 2
                            plot(app.UIAxes,t,uEulerM,"-g*")
                        if strG ~= "-sin(u)-0.3*v"
                            legenda = {'Exata','EulerMelhorado'};
                            tabela = [t.',uExata.',uEulerM.',erroEulerM.'];
                            nomeColunas = {'t','Exata','EulerMelhorado','erroEulerMelhorado'};
                        else
                            legenda = {'EulerMelhorado'};
                            tabela = [t.',uEulerM.'];
                            nomeColunas = {'t','EulerMelhorado'};
                        end
                    case 3
                            plot(app.UIAxes,t,uRK2,"-b+")
                        if strG ~= "-sin(u)-0.3*v"
                            legenda = {'Exata','RK2'};
                            tabela = [t.',uExata.',uRK2.',erroRK2.'];
                            nomeColunas = {'t','Exata','RK2','erroRK2'};
                        else
                            legenda = {'RK2'};
                            tabela = [t.',uRK2.'];
                            nomeColunas = {'t','RK2'};
                        end
                    case 4
                            plot(app.UIAxes,t,uRK2,"-b+")
                        if strG ~= "-sin(u)-0.3*v"
                            legenda = {'Exata','RK4'};
                            tabela = [t.',uExata.',uRK4.',erroRK4.'];
                            nomeColunas = {'t','Exata','RK4','erroRK4'};
                        else
                            legenda = {'RK4'};
                            tabela = [t.',uRK4.'];
                            nomeColunas = {'t','RK4'};
                        end
                    case 5
                        if strG ~= "-sin(u)-0.3*v"
                        plot(app.UIAxes,t,uEuler,"-g*")
                        plot(app.UIAxes,t,uEulerM,"-b+")
                        plot(app.UIAxes,t,uRK2,"-yd")
                        plot(app.UIAxes,t,uRK4,"-kv")
                        legenda = {'Exata','Euler','EulerMelhorado','RK2','RK4'};
                        tabela = [t.',uExata.',uEuler.',uEulerM.',uRK2.',uRK4.',erroEuler.',erroEulerM.',erroRK2.',erroRK4.'];
                        nomeColunas = {'t','Exata','Euler','EulerMelhorado','RK2','RK4',...
                            'erroEuler','erroEulerMelhorado','erroRK2','erroRK4'};
                        else
                        plot(app.UIAxes,t,uEuler,"-g*")
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes,t,uEulerM,"-b+")
                        plot(app.UIAxes,t,uRK2,"-yd")
                        plot(app.UIAxes,t,uRK4,"-kv")
                        legenda = {'Euler','EulerMelhorado','RK2','RK4'};
                        tabela = [t.',uEuler.',uEulerM.',uRK2.',uRK4.'];
                        nomeColunas = {'t','Exata','Euler','EulerMelhorado','RK2','RK4'};
                        end

                end

                T = array2table(tabela);
                app.UITable.Data = T;
                app.UITable.ColumnName = nomeColunas;

                hold(app.UIAxes,"off")
                legend(app.UIAxes,legenda)

            catch ME
                errordlg(ME.message,"Erro","modal")
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AM2SEDUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {512, 512};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {261, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: mSair
        function mSairMenuSelected(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.AM2SEDUIFigure)
            end
        end

        % Value changed function: ddProblemas
        function ddProblemasValueChanged(app, event)
            value = app.ddProblemas.Value;
            switch value
                case "1"    % Problema dinâmico Pêndulo
                    app.UIAxes.Title.String = "Pêndulo";
                    app.eF.Value = "v";
                    strG = "-sin(u)-0.3*v";
                    a = 0;
                    b = 15;
                    n = 100;
                    u0 = pi/2;
                    v0 = 0;
                    slt='off';
                case "2"    % Problema dinâmico Mola-massa s/amortecimento
                    app.UIAxes.Title.String = "Mola-massa s/amortecimento";
                    app.eF.Value = "v";
                    strG = "-16*u";
                    a = 0;
                    b = 4;
                    n = 100;
                    u0 = 9;
                    v0 = 0;
                    slt='off';
                case "3"    % Problema dinâmico Mola-massa c/amortecimento
                    app.UIAxes.Title.String = "Mola-massa c/amortecimento";
                    app.eF.Value = "v";
                    strG = "-v-16*u"; 
                    a = 0;
                    b = 4;
                    n = 100;
                    u0 = 9;
                    v0 = 0;
                    slt='off';
                case "4"    % Problema dinâmico de circuito eletrico
                    app.UIAxes.Title.String = "Circuito elétrico RLC";
                    app.eF.Value = "v"; 
                    strG = "(-200*v-2000*u)/20 "; 
                    a = 0;
                    b = 2;
                    n = 100;
                    u0 = 1;
                    v0 = 0;
                    app.eU0.Editable = "off"; 
                    slt = "off";
                case "5" % Problema dinâmico de Evolução dp preço de mercado
                    app.UIAxes.Title.String = "Evolução do preço de mercado";
                    app.eF.Value = "v"; 
                    strG = "(-80*v-40*u-80)/100"; % (-200*v-20*u-80)/60
                    a = 0;
                    b = 5;
                    n = 200;
                    u0 = 0;
                    v0 = 3;
                    slt = "off";                    
                case "6"    % Problema dinâmico de escolha livre  
                    app.UIAxes.Title.String = "Escolha livre";
                    app.eF.Value = "";
                    strG = "";
                    a = 0;
                    b = 10;
                    n = 100;
                    u0 = 0;
                    v0 = 0;
                    slt = "on";
            end
            app.eG.Value = strG;
            app.eA.Value = a;
            app.eB.Value = b; 
            app.eN.Value = n;
            app.eU0.Value = u0; 
            app.eV0.Value = v0;
            app.eF.Editable = slt;
            app.eG.Editable = slt;
        end

        % Menu selected function: mRelatorio
        function mRelatorioMenuSelected(app, event)
            open 'Relatório_trabalho2_AM2 .pdf'
        end

        % Menu selected function: mAutor
        function mAutorMenuSelected(app, event)
            appAutores
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "On"
                grid(app.UIAxes,"on")
            else
                grid(app.UIAxes,"off")
            end
        end

        % Close request function: AM2SEDUIFigure
        function AM2SEDUIFigureCloseRequest(app, event)
            %delete(app)
            mSairMenuSelected(app, event)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

                    app.UIAxes.Title.String = "Pêndulo";
                    app.eF.Value = "v";
                    app.eG.Value = "-sin(u)-0.3*v";
                    app.eA.Value = 0;
                    app.eB.Value = 15;
                    app.eN.Value = 100;
                    app.eU0.Value = pi/2;
                    app.eV0.Value = 0;
                    app.eF.Editable = 'off';
                    app.eG.Editable = 'off';
                    bAtualizarButtonPushed(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AM2SEDUIFigure = uifigure('Visible', 'off');
            app.AM2SEDUIFigure.Name = 'AM2-SED';
            app.AM2SEDUIFigure.Icon = 'Sem nome.png';
            app.AM2SEDUIFigure.CloseRequestFcn = createCallbackFcn(app, @AM2SEDUIFigureCloseRequest, true);
            app.AM2SEDUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.AM2SEDUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPVI = uipanel(app.LeftPanel);
            app.DadosPVI.Title = 'Dados do PVI - SED';
            app.ftuvLabel = uilabel(app.DadosPVI);
            app.ftuvLabel.Text = 'f(t,u,v) = ';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.eF.Value = 'v';
            app.aLabel = uilabel(app.DadosPVI);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.DadosPVI, 'numeric');
            app.eB = uieditfield(app.DadosPVI, 'numeric');
            app.eB.Value = 15;
            app.bLabel = uilabel(app.DadosPVI);
            app.bLabel.Text = 'b';
            app.eN = uieditfield(app.DadosPVI, 'numeric');
            app.eN.Value = 100;
            app.nLabel = uilabel(app.DadosPVI);
            app.nLabel.Text = 'n';
            app.u0Label = uilabel(app.DadosPVI);
            app.u0Label.Text = 'u0';
            app.eU0 = uieditfield(app.DadosPVI, 'numeric');
            app.eU0.Value = 1.5707963267949;
            app.gtuvLabel = uilabel(app.DadosPVI);
            app.gtuvLabel.Text = 'g(t,u,v) = ';
            app.eG = uieditfield(app.DadosPVI, 'text');
            app.eG.Value = '-sin(u)-0.3*v';
            app.eV0 = uieditfield(app.DadosPVI, 'numeric');
            app.v0Label = uilabel(app.DadosPVI);
            app.v0Label.Text = 'v0';
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = ' Métodos numéricos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos os Métodos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.ProblemasdinmicosDropDownLabel = uilabel(app.LeftPanel);
            app.ProblemasdinmicosDropDownLabel.Text = 'Problemas dinâmicos';
            app.ddProblemas = uidropdown(app.LeftPanel);
            app.ddProblemas.Items = {'Pêndulo', 'Mola-Massa sem amortecimento', 'Mola-Massa com amortecimento', 'Circuito elétrico RLC', 'Evolução do preço de mercado', 'Modo Livre'};
            app.ddProblemas.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.ddProblemas.ValueChangedFcn = createCallbackFcn(app, @ddProblemasValueChanged, true);
            app.ddProblemas.Value = '1';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'T')
            ylabel(app.UIAxes, 'U')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ErroLampLabel = uilabel(app.RightPanel);
            app.ErroLampLabel.Text = 'Erro';
            app.EL = uilamp(app.RightPanel);
            app.Switch = uiswitch(app.RightPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.FicheiroMenu = uimenu(app.AM2SEDUIFigure);
            app.FicheiroMenu.Text = 'Ficheiro';
            app.mRelatorio = uimenu(app.FicheiroMenu);
            app.mRelatorio.MenuSelectedFcn = createCallbackFcn(app, @mRelatorioMenuSelected, true);
            app.mRelatorio.Text = 'Relatório';
            app.mSair = uimenu(app.FicheiroMenu);
            app.mSair.MenuSelectedFcn = createCallbackFcn(app, @mSairMenuSelected, true);
            app.mSair.Text = 'Sair';
            app.SobreMenu = uimenu(app.AM2SEDUIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.mAutor = uimenu(app.SobreMenu);
            app.mAutor.MenuSelectedFcn = createCallbackFcn(app, @mAutorMenuSelected, true);
            app.mAutor.Text = 'Autores';
            % Show the figure after all components are created
            app.AM2SEDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaPVISED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AM2SEDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AM2SEDUIFigure)
        end
    end
end