classdef appMaquiaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AM2CDIUIFigure                  matlab.ui.Figure
        FicheiroMenu                    matlab.ui.container.Menu
        RelatrioMenu                    matlab.ui.container.Menu
        SAIRMenu                        matlab.ui.container.Menu
        SobreMenu                       matlab.ui.container.Menu
        AutoresMenu                     matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        tgFrVr                          matlab.ui.container.Tab
        pResultados                     matlab.ui.container.Panel
        ErronafunoLamp                  matlab.ui.control.Lamp
        ErronafunoLampLabel             matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        pDados                          matlab.ui.container.Panel
        IntegraodafunofxPanel           matlab.ui.container.Panel
        eR                              matlab.ui.control.NumericEditField
        reafxLabel                      matlab.ui.control.Label
        nEditFieldLabel                 matlab.ui.control.Label
        INN                             matlab.ui.control.NumericEditField
        INumdp                          matlab.ui.control.DropDown
        IntegraonumricaDropDownLabel    matlab.ui.control.Label
        Dmetodos                        matlab.ui.container.ButtonGroup
        CBderivada2                     matlab.ui.control.CheckBox
        Diferenasfinitasem3pontosLabel  matlab.ui.control.Label
        Diferenasfinitasem2pontosLabel  matlab.ui.control.Label
        DFC                             matlab.ui.control.RadioButton
        DFR3                            matlab.ui.control.RadioButton
        DFP3                            matlab.ui.control.RadioButton
        DFR                             matlab.ui.control.RadioButton
        DFP                             matlab.ui.control.RadioButton
        bAtualizar                      matlab.ui.control.Button
        FP                              matlab.ui.container.Panel
        bLabel                          matlab.ui.control.Label
        eB                              matlab.ui.control.NumericEditField
        hLabel                          matlab.ui.control.Label
        eH                              matlab.ui.control.NumericEditField
        eA                              matlab.ui.control.NumericEditField
        aLabel                          matlab.ui.control.Label
        eF                              matlab.ui.control.EditField
        fxLabel                         matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        tgFr2Vr                         matlab.ui.container.Tab
        pResultados_2                   matlab.ui.container.Panel
        ErronafunoLamp_2                matlab.ui.control.Lamp
        ErronafunoLamp_2Label           matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        UIAxes_2                        matlab.ui.control.UIAxes
        pDados_2                        matlab.ui.container.Panel
        bAtualizar_2                    matlab.ui.control.Button
        FP_2                            matlab.ui.container.Panel
        eA_3                            matlab.ui.control.NumericEditField
        cLabel                          matlab.ui.control.Label
        dLabel                          matlab.ui.control.Label
        eB_3                            matlab.ui.control.NumericEditField
        h2Label                         matlab.ui.control.Label
        eH_3                            matlab.ui.control.NumericEditField
        bLabel_2                        matlab.ui.control.Label
        eB_2                            matlab.ui.control.NumericEditField
        h1Label                         matlab.ui.control.Label
        eH_2                            matlab.ui.control.NumericEditField
        eA_2                            matlab.ui.control.NumericEditField
        aLabel_2                        matlab.ui.control.Label
        eF_2                            matlab.ui.control.EditField
        fxyLabel                        matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
                    
        function [x,y,dydx] = NDerivadaDFP(~,f,a,b,h,y)
            %   29/04/2021  Arménio Correia armenioc@isec.pt
            %   Adaptado por:
            %   Nuno Domingues  a2020109910@isec.pt
            %   Miguel Neves    a2020146521@isec.pt
            %   Daniel Albino   a2020134077@isec.pt
            % Inputs:
            %   f = função a derivar
            %   y = f(x)
            %   a = ponto inicial do intervalo [a,b]
            %   b = ponto final do intervalo [a,b]
            %   h = amplitude de cada intervalo
            % Outputs:
            %   dydx = derivada da função
            %   x = intervalo da função eixo dos X
            
            x = a:h:b; % repartição do intervalo [a,b]
            n = length(x); % n = número de sub-intervalos do X
                if nargin == 5 % Se o numero de argumentos da função = 5 
                    y = f(x);               
                end
            dydx = zeros(1,n); % Alocação de memória
                for i = 1:n-1
                    dydx(i) = (y(i+1)-y(i))/h; % Cálculo da derivada i da função
                end
            dydx(n) = dydx(n-1); % Atribuição da penúltima derivada à última derivada 
            %dydx(n) = (y(n)-y(n-1))/h;
        end
            
        
        function [x,y,dydx] = NDerivadaDFR(~,f,a,b,h,y)
            % Inputs:
            %   f = função a derivar
            %   y = f(x)
            %   a = ponto inicial do intervalo [a,b]
            %   b = ponto final do intervalo [a,b]
            %   h = amplitude de cada intervalo
            % Outputs:
            %   dydx = derivada da função
            %   x = intervalo da função eixo dos X
            
            x = a:h:b; % repartição do intervalo [a,b]
            n = length(x); % n = número de sub-intervalos do X
                if nargin == 5 % Se o numero de argumentos da função = 5 
                    y = f(x);
                end
            dydx = zeros(1,n); % Alocação de memória
                for i = 2:n
                    dydx(i) = (y(i)-y(i-1))/h; % Cálculo da derivada i da função
                end
            dydx(1) = dydx(2); % Atribuição da segunda derivada à primeira derivada 
        end
        
        function [x,y,dydx] = NDerivadaDFP3(~,f,a,b,h,y)
            % Inputs:
            %   f = função a derivar
            %   y = f(x)
            %   a = ponto inicial do intervalo [a,b]
            %   b = ponto final do intervalo [a,b]
            %   h = amplitude de cada intervalo
            % Outputs:
            %   dydx = derivada da função
            %   x = intervalo da função eixo dos X
            
            c = b+2*h; % aumento do intervalo [a,b], para poder calcular as derivadas até b
            t = a:h:c; % repartição do intervalo [a,c]
            n1 = length(t); % n = número de sub-intervalos do t
                if nargin == 5 % Se o numero de argumentos da função = 5 
                    y1 = f(t);
                end
            dydx1 = zeros(1,n1); % Alocação de memória
                for i = 1:n1-2
                    dydx1(i) = (-3*y1(i)+4*y1(i+1)-y1(i+2))/(2*h); % Cálculo da derivada i da função
                end
              
            x = a:h:b; % repartição do intervalo [a,b]
            n = length(x); % n = número de sub-intervalos do n
                if nargin == 5 % Se o numero de argumentos da função = 5 
                        y = f(x);
                end
            dydx = zeros(1,n); % Alocação de memória
                for i=1:n
                    dydx(i) = dydx1(i); % copia o valor em dydx1(i) para dydx(i)
                end
        end
        
        function [x,y,dydx] = NDerivadaDFR3(~,f,a,b,h,y)
            % Inputs:
            %   f = função a derivar
            %   y = f(x)
            %   a = ponto inicial do intervalo [a,b]
            %   b = ponto final do intervalo [a,b]
            %   h = amplitude de cada intervalo
            % Outputs:
            %   dydx = derivada da função
            %   x = intervalo da função eixo dos X
            
            c = a-2*h; % aumento do intervalo [a,b], para poder calcular as derivadas
            t = c:h:b; % repartição do intervalo [c,b]
            n1 = length(t); % n = número de sub-intervalos do t
                if nargin == 5 % Se o numero de argumentos da função = 5 
                    y1 = f(t);
                end
            dydx1 = zeros(1,n1); % Alocação de memória
                for i = 3:n1
                    dydx1(i) = (y1(i-2)-4*y1(i-1)+3*y1(i))/(2*h); % Cálculo da derivada i da função
                end
           
            x = a:h:b; % repartição do intervalo [a,b]
            n = length(x); % n = número de sub-intervalos do n
                if nargin == 5 % Se o numero de argumentos da função = 5 
                    y = f(x);
                end
            dydx = zeros(1,n); % Alocação de memória
                for i = 1:n
                    dydx(i) = dydx1(i+2); % copia o valor em dydx1(i+2) para dydx(i)
                end

        end
        
        function [x,y,dydx] = NDerivadaDFC3(~,f,a,b,h,y)
            % Inputs:
            %   f = função a derivar
            %   y = f(x)
            %   a = ponto inicial do intervalo [a,b]
            %   b = ponto final do intervalo [a,b]
            %   h = amplitude de cada intervalo
            % Outputs:
            %   dydx = derivada da função
            %   x = intervalo da função eixo dos X
            
            c = a-h; % aumento do intervalo [a,b], para poder calcular as derivadas
            d = b+h; % aumento do intervalo [a,b]
            t = c:h:d; % repartição do intervalo [c,d]
            n1 = length(t); % n = número de sub-intervalos do t
                if nargin == 5 % Se o numero de argumentos da função = 5 
                    y1 = f(t);
                end
            dydx1 = zeros(1,n1); % Alocação de memória
                for i = 2:n1-1
                    dydx1(i) = (y1(i+1)-y1(i-1))/(2*h); % Cálculo da derivada i da função
                end
            
            x = a:h:b; % repartição do intervalo [a,b]
            n = length(x); % n = número de sub-intervalos do n
                if nargin == 5 % Se o numero de argumentos da função = 5 
                    y = f(x);
                end
            dydx = zeros(1,n); % Alocação de memória
                for i = 1:n
                    dydx(i) = dydx1(i+1); % copia o valor em dydx1(i+1) para dydx(i)
                end

        end
        
        function T = trapezio(~,f,a,b,n)

            h = (b-a)/n;
            x = a;
            s = 0;
            for i=1:n-1
                x = x + h;
                s = s + f(x);
            end
            T=(h/2)*(f(a)+2*s+f(b));
           
        end
        
        function Out_s = simpson(~,f,a,b,n)
            
            h = (b-a)/n;
            x = a;
            s = 0;
            for i=1:n-1
                x = x + h;
                if rem(i, 2)== 0
                s = s + 2*f(x);
                else
                s = s + 4*f(x);
                end
            end
            Out_s=(h/3)*(f(a)+s+f(b));
           
        end
        
        function [x,y,dydx] = NDerivada2(~,f,a,b,h,y)
            % Inputs:
            %   f = função a derivar
            %   y = f(x)
            %   a = ponto inicial do intervalo [a,b]
            %   b = ponto final do intervalo [a,b]
            %   h = amplitude de cada intervalo
            % Outputs:
            %   dydx = 2ºderivada da função
            %   x = intervalo da função eixo dos X
            
            c = a-h; % aumento do intervalo [a,b], para poder calcular as derivadas
            d = b+h; % aumento do intervalo [a,b]
            t = c:h:d; % repartição do intervalo [c,d]
            n1 = length(t); % n = número de sub-intervalos do t
                if nargin == 5 % Se o numero de argumentos da função = 5 
                    y1 = f(t);
                end
            dydx1 = zeros(1,n1); % Alocação de memória
                for i = 2:n1-1
                    dydx1(i) = (y1(i+1)-2*y1(i)+y1(i-1))/(h*h); % Cálculo da derivada i da função
                end       

            x = a:h:b; % repartição do intervalo [a,b]
            n = length(x); % n = número de sub-intervalos do n
                if nargin == 5 % Se o numero de argumentos da função = 5 
                    y = f(x);
                end
            dydx = zeros(1,n); % Alocação de memória
                for i = 1:n
                    dydx(i) = dydx1(i+1); % copia o valor em dydx1(i+1) para dydx(i)
                end 
        end

    end
    
    methods (Access = private)
        
        function YN = isHarmonica(~,f)
            syms x y;
            if(diff(f,x,2) + diff(f,y,2) == 0)
                YN = 1;
            else
                YN = 0;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cla(app.UIAxes2,"reset")
            app.UIAxes2.Title.String = '';
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Box = 'on';
            app.INN.Editable = "off";
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)

            %Adaptado por:
            %Nuno Domingues  a2020109910@isec.pt
            %Miguel Neves    a2020146521@isec.pt
            %Daniel Albino   a2020134077@isec.pt
 
            %Data: 10/06/2021

            strF = app.eF.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA.Value; h = app.eH.Value; b = app.eB.Value; 
            
             % Validação dos campos de entrada
   
            if (isempty(a) || ~isscalar(a) || ~isreal(a)) 
                errordlg("O parâmetro a deve ser ESCALAR e REAL","ERRO","modal")
                app.eA.BackgroundColor = [1,0,0];
            else
                app.eA.BackgroundColor = 'w';
            end
        
         
            if (isempty(b) || ~isscalar(b) || ~isreal(b) || b<a) 
                errordlg("O parâmetro b deve ser ESCALAR, REAL e MAIOR que a","ERRO","modal")
                app.eB.BackgroundColor = [1,0,0];
            else
                app.eB.BackgroundColor = 'w';
            end        
       
           
            if (isempty(h) || ~isscalar(h) || ~isreal(h)) 
                errordlg("O parâmetro n deve ser ESCALAR e REAL","ERRO","modal")
                app.eH.BackgroundColor = [1,0,0];
            else
                app.eH.BackgroundColor = 'w';
            end        
            
            syms y(x)
         
            erroFuncao = MException("MATLAB:bAtualizar:badF",...
                "Introduza uma função em x");
       try
          try
              y = f(x);
              app.eF.BackgroundColor = "w";
          catch ME
              app.eF.BackgroundColor = [1,0,0]; %[r,g,b]
              app.ErronafunoLamp.Color = "r";
              throw(erroFuncao)
          end

            diffSym = diff(f(x));
            dydxExata = @(x)eval(vectorize(diffSym));
            derivadaLatex = latex(diffSym);
            cla(app.UIAxes2,"reset")
            app.UIAxes2.Title.String = '';
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Box = 'on';
            text(app.UIAxes2,0.1,0.5,['$' derivadaLatex '$'],'Interpreter',"latex","FontSize",18)


            diff_2 = app.CBderivada2.Value;
            
            
            escolhaMetodos = app.Dmetodos.SelectedObject;
            metodoSelecionado = find([app.DFP,...
                app.DFR,...
                app.DFP3,...
                app.DFR3,...
                app.DFC]==escolhaMetodos);
            
            
             switch metodoSelecionado
                    case 1
                        app.UIAxes.Title.String = 'D.F.Progressivas';
                        [x,y,dydxAprox] = NDerivadaDFP(app,f,a,b,h);
                        dydxExata = dydxExata(x);
                        erro = abs(dydxExata-dydxAprox);
                        
                        if diff_2 == 1
                        [x,y,dydxAprox2] = NDerivada2(app,f,a,b,h);
                        plot(app.UIAxes,x,y,"-r+",x,dydxExata,"-go", x,dydxAprox, "-b*",x,dydxAprox2,"--py")
                        legend(app.UIAxes,'y=f(x)','1ºderivada exata','1ºderivada aprox','2ºderivada Aprox')
                        else
                        plot(app.UIAxes,x,y,"-r+", x,dydxExata, "-b*",x,dydxAprox,"-go")
                        legend(app.UIAxes,'y=f(x)','1ºderivada exata','1ºderivada aprox')
                        end
                        
                        tabela = [x.',y.',dydxExata.',dydxAprox.',erro.'];
                        nomeColunas = {'x','y=f(x)','diff exata','diff aprox','Erro (D.F.P)'};
  
                    case 2
                        app.UIAxes.Title.String = 'D.F.Regressivas';
                        [x,y,dydxAprox] = NDerivadaDFR(app,f,a,b,h);
                        dydxExata = dydxExata(x);
                        erro = abs(dydxExata-dydxAprox);
                        
                        if diff_2 == 1
                        [x,y,dydxAprox2] = NDerivada2(app,f,a,b,h);
                        plot(app.UIAxes,x,y,"-r+",x,dydxExata,"-go", x,dydxAprox, "-b*",x,dydxAprox2,"--py")
                        legend(app.UIAxes,'y=f(x)','1ºderivada exata','1ºderivada aprox','2ºderivada Aprox')
                        else
                        plot(app.UIAxes,x,y,"-r+", x,dydxExata, "-b*",x,dydxAprox,"-go")
                        legend(app.UIAxes,'y=f(x)','1ºderivada exata','1ºderivada aprox')
                        end
                        
                        tabela = [x.',y.',dydxExata.',dydxAprox.',erro.'];
                        nomeColunas = {'x','y=f(x)','diff exata','diff aprox','Erro (D.F.R)'};

                    case 3
                        app.UIAxes.Title.String = 'D.F.Progressivas(3 pontos)';
                        [x,y,dydxAprox] = NDerivadaDFP3(app,f,a,b,h);
                        dydxExata = dydxExata(x);
                        erro = abs(dydxExata-dydxAprox);
                        
                        if diff_2 == 1
                        [x,y,dydxAprox2] = NDerivada2(app,f,a,b,h);
                        plot(app.UIAxes,x,y,"-r+",x,dydxExata,"-go", x,dydxAprox, "-b*",x,dydxAprox2,"--py")
                        legend(app.UIAxes,'y=f(x)','1ºderivada exata','1ºderivada aprox','2ºderivada Aprox')
                        else
                        plot(app.UIAxes,x,y,"-r+", x,dydxExata, "-b*",x,dydxAprox,"-go")
                        legend(app.UIAxes,'y=f(x)','1ºderivada exata','1ºderivada aprox')
                        end
                        
                        tabela = [x.',y.',dydxExata.',dydxAprox.',erro.'];
                        nomeColunas = {'x','y=f(x)','diff exata','diff aprox','Erro (D.F.R)'};
  
                    case 4
                        app.UIAxes.Title.String = 'D.F.Regressivas(3 pontos)';
                        [x,y,dydxAprox] = NDerivadaDFR3(app,f,a,b,h);
                        dydxExata = dydxExata(x);
                        erro = abs(dydxExata-dydxAprox);
                        
                        if diff_2 == 1
                        [x,y,dydxAprox2] = NDerivada2(app,f,a,b,h);
                        plot(app.UIAxes,x,y,"-r+",x,dydxExata,"-go", x,dydxAprox, "-b*",x,dydxAprox2,"--py")
                        legend(app.UIAxes,'y=f(x)','1ºderivada exata','1ºderivada aprox','2ºderivada Aprox')
                        else
                        plot(app.UIAxes,x,y,"-r+", x,dydxExata, "-b*",x,dydxAprox,"-go")
                        legend(app.UIAxes,'y=f(x)','1ºderivada exata','1ºderivada aprox')
                        end
                        
                        tabela = [x.',y.',dydxExata.',dydxAprox.',erro.'];
                        nomeColunas = {'x','y=f(x)','diff exata','diff aprox','Erro (D.F.R)'};
  
                    case 5
                        app.UIAxes.Title.String = 'D.F.Centradas(3 pontos)';
                        [x,y,dydxAprox] = NDerivadaDFC3(app,f,a,b,h);
                        dydxExata = dydxExata(x);
                        erro = abs(dydxExata-dydxAprox);
                        
                        if diff_2 == 1
                        [x,y,dydxAprox2] = NDerivada2(app,f,a,b,h);
                        plot(app.UIAxes,x,y,"-r+",x,dydxExata,"-go", x,dydxAprox, "-b*",x,dydxAprox2,"--py")
                        legend(app.UIAxes,'y=f(x)','1ºderivada exata','1ºderivada aprox','2ºderivada Aprox')
                        else
                        plot(app.UIAxes,x,y,"-r+", x,dydxExata, "-b*",x,dydxAprox,"-go")
                        legend(app.UIAxes,'y=f(x)','1ºderivada exata','1ºderivada aprox')
                        end
                        
                        tabela = [x.',y.',dydxExata.',dydxAprox.',erro.'];
                        nomeColunas = {'x','y=f(x)','diff exata','diff aprox','Erro (D.F.R)'};
              end

                T = array2table(tabela);
                app.UITable.Data = T;
                app.UITable.ColumnName = nomeColunas;
                

                IN = app.INumdp.Value;

                if IN == "Regra dos Trapézios"
                    n = app.INN.Value;
                    T = trapezio(app,f,a,b,n);
                    app.eR.Value = T;
                    x = a:0.1:b;
                    area(x,y)
                end
                
                if IN == "Regra de Simpson"
                    n = app.INN.Value;
                    Out_s = simpson(app,f,a,b,n);
                    app.eR.Value = Out_s;
                    x = a:0.1:b;
                    area(x,y)   
                end
        catch ME
            errordlg(ME.message,"Erro","modal")
        end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "On"
                grid(app.UIAxes,"on")
            else
                grid(app.UIAxes,"off")
            end
        end

        % Menu selected function: SAIRMenu
        function SAIRMenuSelected(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.AM2CDIUIFigure)
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            appAutores
        end

        % Close request function: AM2CDIUIFigure
        function AM2CDIUIFigureCloseRequest(app, event)
            SAIRMenuSelected(app, event)
        end

        % Value changed function: INumdp
        function INumdpValueChanged(app, event)
            value = app.INumdp.Value;
         
                if value == "----"
                    app.INN.Value = 0;
                    app.eR.Value = 0;
                    app.INN.Editable = "off";
                else
                    app.INN.Editable = "on";
                end

        end

        % Button pushed function: bAtualizar_2
        function bAtualizar_2ButtonPushed(app, event)
            strFxy = app.eF_2.Value;
            f =@(x,y) eval(vectorize (strFxy));
            a=app.eA_2.Value; h2=app.eH_2.Value;b=app.eB_2.Value;
            c=app.eA_3.Value; h3=app.eH_3.Value;d=app.eB_3.Value;
            
            [x,y] = meshgrid(a:h2:b,c:h3:d);
            z = f(x,y);
            mesh(app.UIAxes_2,x,y,z);
            
            YN = isHarmonica(app,f);
            if YN
                cla(app.UIAxes2_2,"reset")
                app.UIAxes2_2.Title.String = '';
                app.UIAxes2_2.XTick = [];
                app.UIAxes2_2.YTick = [];
                app.UIAxes2_2.Box = 'on';
                text(app.UIAxes2_2,0.1,0.5,'f é harmónica',"BackgroundColor",'g','Color','w');
            else
                cla(app.UIAxes2_2,"reset")
                app.UIAxes2_2.Title.String = '';
                app.UIAxes2_2.XTick = [];
                app.UIAxes2_2.YTick = [];
                app.UIAxes2_2.Box = 'on';
                text(app.UIAxes2_2,0.1,0.5,'f não é harmónica',"BackgroundColor",'r','Color','w');
            end
        end

        % Value changed function: CBderivada2
        function CBderivada2ValueChanged(app, event)
 
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open 'Relatório_trabalho_3_AM2.pdf'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AM2CDIUIFigure = uifigure('Visible', 'off');
            app.AM2CDIUIFigure.Name = 'AM2-CDI';
            app.AM2CDIUIFigure.Icon = 'Sem nome.png';
            app.AM2CDIUIFigure.CloseRequestFcn = createCallbackFcn(app, @AM2CDIUIFigureCloseRequest, true);
            app.FicheiroMenu = uimenu(app.AM2CDIUIFigure);
            app.FicheiroMenu.Text = 'Ficheiro';
            app.RelatrioMenu = uimenu(app.FicheiroMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.SAIRMenu = uimenu(app.FicheiroMenu);
            app.SAIRMenu.MenuSelectedFcn = createCallbackFcn(app, @SAIRMenuSelected, true);
            app.SAIRMenu.Text = 'SAIR';
            app.SobreMenu = uimenu(app.AM2CDIUIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.TabGroup = uitabgroup(app.AM2CDIUIFigure);
            app.tgFrVr = uitab(app.TabGroup);
            app.tgFrVr.Title = 'Função real de variável real y = f(x)';
            app.pDados = uipanel(app.tgFrVr);
            app.UIAxes2 = uiaxes(app.pDados);
            app.FP = uipanel(app.pDados);
            app.FP.Title = 'Função';
            app.fxLabel = uilabel(app.FP);
            app.fxLabel.Text = 'f(x)=';
            app.eF = uieditfield(app.FP, 'text');
            app.eF.Value = 'sin(x)';
            app.aLabel = uilabel(app.FP);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.FP, 'numeric');
            app.eH = uieditfield(app.FP, 'numeric');
            app.eH.Value = 0.1;
            app.hLabel = uilabel(app.FP);
            app.hLabel.Text = 'h';
            app.eB = uieditfield(app.FP, 'numeric');
            app.eB.Value = 3;
            app.bLabel = uilabel(app.FP);
            app.bLabel.Text = 'b';
            app.bAtualizar = uibutton(app.pDados, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.Dmetodos = uibuttongroup(app.pDados);
            app.Dmetodos.Title = 'Derivação numérica (escolha um método)';
            app.DFP = uiradiobutton(app.Dmetodos);
            app.DFP.Text = 'Progressivas';
            app.DFP.Value = true;
            app.DFR = uiradiobutton(app.Dmetodos);
            app.DFR.Text = 'Regressivas';
            app.DFP3 = uiradiobutton(app.Dmetodos);
            app.DFP3.Text = 'Progressivas';
            app.DFR3 = uiradiobutton(app.Dmetodos);
            app.DFR3.Text = 'Regressivas';
            app.DFC = uiradiobutton(app.Dmetodos);
            app.DFC.Text = 'Centradas';
            app.Diferenasfinitasem2pontosLabel = uilabel(app.Dmetodos);
            app.Diferenasfinitasem2pontosLabel.Text = 'Diferenças finitas em 2 pontos';
            app.Diferenasfinitasem3pontosLabel = uilabel(app.Dmetodos);
            app.Diferenasfinitasem3pontosLabel.Text = 'Diferenças finitas em 3 pontos';
            app.CBderivada2 = uicheckbox(app.Dmetodos);
            app.CBderivada2.ValueChangedFcn = createCallbackFcn(app, @CBderivada2ValueChanged, true);
            app.CBderivada2.Text = '2º Derivada';
            app.IntegraodafunofxPanel = uipanel(app.pDados);
            app.IntegraodafunofxPanel.Title = 'Integração da função f(x)';
            app.IntegraonumricaDropDownLabel = uilabel(app.IntegraodafunofxPanel);
            app.IntegraonumricaDropDownLabel.Text = 'Integração numérica';
            app.INumdp = uidropdown(app.IntegraodafunofxPanel);
            app.INumdp.Items = {'----', 'Regra dos Trapézios', 'Regra de Simpson'};
            app.INumdp.ItemsData = {'----', 'Regra dos Trapézios', 'Regra de Simpson'};
            app.INumdp.ValueChangedFcn = createCallbackFcn(app, @INumdpValueChanged, true);
            app.INumdp.Value = '----';
            app.INN = uieditfield(app.IntegraodafunofxPanel, 'numeric');
            app.nEditFieldLabel = uilabel(app.IntegraodafunofxPanel);
            app.nEditFieldLabel.Text = 'n';
            app.reafxLabel = uilabel(app.IntegraodafunofxPanel);
            app.reafxLabel.Text = 'Área (f(x))=';
            app.eR = uieditfield(app.IntegraodafunofxPanel, 'numeric');
            app.eR.Editable = 'off';
            app.pResultados = uipanel(app.tgFrVr);
            app.UIAxes = uiaxes(app.pResultados);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.pResultados);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Switch = uiswitch(app.pResultados, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.ErronafunoLampLabel = uilabel(app.pResultados);
            app.ErronafunoLampLabel.Text = 'Erro na função';
            app.ErronafunoLamp = uilamp(app.pResultados);
            app.tgFr2Vr = uitab(app.TabGroup);
            app.tgFr2Vr.Title = 'Função real de 2 variáveis reais z = f(x,y)';
            app.pDados_2 = uipanel(app.tgFr2Vr);
            app.UIAxes2_2 = uiaxes(app.pDados_2);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.FP_2 = uipanel(app.pDados_2);
            app.FP_2.Title = 'Função';
            app.fxyLabel = uilabel(app.FP_2);
            app.fxyLabel.Text = 'f(x,y)=';
            app.eF_2 = uieditfield(app.FP_2, 'text');
            app.eF_2.Value = 'x^2 + y^2';
            app.aLabel_2 = uilabel(app.FP_2);
            app.aLabel_2.Text = 'a';
            app.eA_2 = uieditfield(app.FP_2, 'numeric');
            app.eA_2.Value = -4;
            app.eH_2 = uieditfield(app.FP_2, 'numeric');
            app.eH_2.Value = 0.1;
            app.h1Label = uilabel(app.FP_2);
            app.h1Label.Text = 'h1';
            app.eB_2 = uieditfield(app.FP_2, 'numeric');
            app.eB_2.Value = 4;
            app.bLabel_2 = uilabel(app.FP_2);
            app.bLabel_2.Text = 'b';
            app.eH_3 = uieditfield(app.FP_2, 'numeric');
            app.eH_3.Value = 0.1;
            app.h2Label = uilabel(app.FP_2);
            app.h2Label.Text = 'h2';
            app.eB_3 = uieditfield(app.FP_2, 'numeric');
            app.eB_3.Value = 4;
            app.dLabel = uilabel(app.FP_2);
            app.dLabel.Text = 'd';
            app.cLabel = uilabel(app.FP_2);
            app.cLabel.Text = 'c';
            app.eA_3 = uieditfield(app.FP_2, 'numeric');
            app.eA_3.Value = -4;
            app.bAtualizar_2 = uibutton(app.pDados_2, 'push');
            app.bAtualizar_2.ButtonPushedFcn = createCallbackFcn(app, @bAtualizar_2ButtonPushed, true);
            app.bAtualizar_2.Text = 'Atualizar';
            app.pResultados_2 = uipanel(app.tgFr2Vr);
            app.UIAxes_2 = uiaxes(app.pResultados_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Switch_2 = uiswitch(app.pResultados_2, 'slider');
            app.ErronafunoLamp_2Label = uilabel(app.pResultados_2);
            app.ErronafunoLamp_2Label.Text = 'Erro na função';
            app.ErronafunoLamp_2 = uilamp(app.pResultados_2);
            % Show the figure after all components are created
            app.AM2CDIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquiaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AM2CDIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AM2CDIUIFigure)
        end
    end
end